classdef chess < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        InstructionsPanel           matlab.ui.container.Panel
        ScanQRCodeButton            matlab.ui.control.Button
        MovesListBox                matlab.ui.control.ListBox
        UIAxes                      matlab.ui.control.UIAxes
        TeamKlondikeTheArtofDigitalChessLabel  matlab.ui.control.Label
        GamePlayPanel               matlab.ui.container.Panel
        CurrentInstructionTextAreaLabel  matlab.ui.control.Label
        CurrentInstructionTextArea  matlab.ui.control.TextArea
        TextArea                    matlab.ui.control.TextArea
        StartGameButton             matlab.ui.control.Button
        LampLabel                   matlab.ui.control.Label
        Lamp                        matlab.ui.control.Lamp
        MoveNumberEditFieldLabel    matlab.ui.control.Label
        MoveNumberEditField         matlab.ui.control.NumericEditField
        CheckLabel                  matlab.ui.control.Label
        CheckLamp                   matlab.ui.control.Lamp
    end


    properties (Access = public)
        start_gp
        serv_up
        serv_pick
        serv_trav
        RUN_TIME
        DISP_TIME
        pieceimgs
        board
        pieces
        types
        rto_out
        pnames
        cam
        plotshape
        plotcolor
        plotsize
        ins_list
        STOPPED
        T1      % Game Pause
        T2      % Game Continue
        T_START % Intial Game Start time
        T_PAUSE % Total Pause Time
        T_STOP  % Game Stop time
        move_list
        ins_num
        move_num
        
        motor_x
        motor_y
        load_cell
        tg
    end

    methods (Access = public)
        
        
        function str = formatTimeFcn(app,float_time)
            % Format the Time String
            float_time = abs(float_time);
            hrs = floor(float_time/3600);
            mins = floor(float_time/60 - 60*hrs);
            secs = float_time - 60*(mins + 60*hrs);
            h = sprintf('%1.0f:',hrs);
            m = sprintf('%1.0f:',mins);
            s = sprintf('%1.3f',secs);
            if hrs < 10
                h = sprintf('0%1.0f:',hrs);
            end
            if mins < 10
                m = sprintf('0%1.0f:',mins);
            end
            if secs < 9.9995
                s = sprintf('0%1.3f',secs);
            end
            str = [h m s];
        end
        % Plots black squares
        function plotBoard(app)
            for i = 1 : 8
                if (rem(i,2) == 0)
                    j = 2;
                else 
                    j = 1;
                end
                while j <= 8
                    plot(app.UIAxes,i,j,'s','MarkerSize',52,'MarkerEdgeColor','black','MarkerFaceColor','black')
                    hold(app.UIAxes,'on')
                    j = j +2;
                end
            end
        end
        function plotSpace(app,i,j)
            if (rem(i,2) == 0) 
               if (rem(j,2) == 0)
                   plot(app.UIAxes,i,j,'s','MarkerSize',52,'MarkerEdgeColor','black','MarkerFaceColor','black')
               else
                   plot(app.UIAxes,i,j,'s','MarkerSize',52,'MarkerEdgeColor','w','MarkerFaceColor','w')
               end
            else
               if (rem(j,2) == 0)
                   plot(app.UIAxes,i,j,'s','MarkerSize',52,'MarkerEdgeColor','w','MarkerFaceColor','w')
               else
                   plot(app.UIAxes,i,j,'s','MarkerSize',52,'MarkerEdgeColor','black','MarkerFaceColor','black')
               end
            end
        end
        
        function plotPieces(app)
            for i = 1 : 8
                for j = 1 : 8
                    if app.pieces(i,j,1) ~= 0
                        plotPiece(app,i,j);
                    end
                end
            end
        end
        % Plots an individual piece
        function plotPiece(app,i,j)
            if app.pieces(i,j,1) ~= 0
                [c,s] = ismember(app.pieces(i,j,1),app.types);
                
                c = rem(s,4);
                if c == 0
                    c = 4;
                end
                s = ceil(s/4);
                t = app.pnames(c,(s)*2-1);
                if t == 'B' 
                    str = 'kw';
                else
                    str = 'wk';
                end
                plot(app.UIAxes,i,j,app.plotshape(s),'MarkerSize',app.plotsize(s),'MarkerEdgeColor',app.plotcolor(c),'MarkerFaceColor',str(1),'LineWidth',4)
                str2 = strcat(app.pnames(c,s*2-1),app.pnames(c,s*2));
                text(app.UIAxes,i-.2,j,str2,'FontSize',12,'Color',str(2))
            end
        end
        function dispQR(app)
            for i = 1 : length(app.ins_list)
                app.MovesListBox.Items(i) = app.ins_list(i);
            end
        end
        function scanQR(app)
            app.ins_list = qr_scan(app.cam);
            dispQR(app);
        end
        function game_play(app)
            
            app.T_START = clock;
            while (app.ins_num <= length(app.ins_list))
                
                % Update move_list / queue
                app.move_list = create_queue(app.ins_list,app.ins_num,app.board,app.pieces);
                
                %% Loop through move queue
                app.move_num = 1;
                while app.move_num <= length(app.move_list) 
                    app.T1 = clock;
                    while app.STOPPED == 1
                    end
                    app.T2 = clock;
                    
                    app.RUN_TIME = etime(clock,(app.T_START + app.T_PAUSE));
                    app.TextArea.Value = formatTimeFcn(app.RUN_TIME);
                    
                    % Update GUI
                    app.ins_num = app.move_list{1,app.move_num}{1};
                    app.MoveNumberEditField.Value = app.ins_num;
                    app.CurrentInstructionTextArea.Value = ins2str(cell2mat(app.ins_list(app.ins_num)),rem(app.ins_num,2),'');
                    ins_chk = cell2mat(app.ins_list(app.ins_num));
                    if ins_chk(2) == 's'
                        app.CheckLamp.Color = [1 1 0];
                    else
                        app.CheckLamp.Color = [.94 .94 .94];
                    end
                    %app.MoveNumberEditField.Value = app.ins_num;
                    % Move Piece
                    
                    updateboard(app);
                    motor_call(app);
                    % Update GUI
                    pause(0.3)
                    
                    app.move_num = app.move_num + 1;
                end
                app.CurrentInstructionTextArea.Value = {'Rescanning board...'};
                % Update board
                move_motors(0,app.motor_y,app.serv_trav,0,app.motor_y);
                %app.motor_y = 0;
                [app.board,app.pieces] = imageproc(app.cam,app.types,4,3);
                
                % Increment to next instruction
                app.ins_num = app.move_list{1,app.move_num-1}{1} + 1;
            end
            move_motors(0,0,4,0,app.motor_y);
            app.RUN_TIME = etime(clock,(app.T_START + app.T_PAUSE));
            app.TextArea.Value = formatTimeFcn(app.RUN_TIME);
            app.CheckLamp.Color = [0 1 0];
            app.CurrentInstructionTextArea.Value = {'Check mate!'};
        end
        
        function motor_call(app) 
            % Store move 
            move = app.move_list(app.move_num);
            
            start = cell2mat(app.move_list{1,app.move_num}(2));
            stop  = cell2mat(app.move_list{1,app.move_num}(3));
            stop(1) = stop(1) + (stop(1)-4).*.05;
            if stop(2) >= 8
                %stop(2) = stop(2) + .2;
            end
            if start(2) >= 8
                start(2) = start(2) - .2;
            end
            %stop(2) = stop(2) - (stop(2).*.025);
            start(2) = start(2);% - (start(2).*.02);
            % Call motor control function
            % pick up piece
            app.T1 = clock;
            while is_load() == 0
                set(app.Lamp, 'Color', [1 1 0])
            end
            set(app.Lamp, 'Color', [0 1 0])
            app.T2 = clock;
            app.T_PAUSE = app.T_PAUSE + app.T2 - app.T1;
            
            move_motors(start(2), start(1), app.serv_trav, 0, app.motor_y);
            app.T1 = clock;
            while is_load() == 0
                set(app.Lamp, 'Color', [1 1 0])
            end
            set(app.Lamp, 'Color', [0 1 0])
            app.T2 = clock;
            %if app.T2 - app.T1 > .05
                app.T_PAUSE = app.T_PAUSE + app.T2 - app.T1;
            %end
            move_motors(start(2), app.motor_y, app.serv_pick, 1, app.motor_y);
            app.motor_y = start(1);
            pause(.2);
            % Raise z axis
            app.T1 = clock;
            while is_load() == 0
                set(app.Lamp, 'Color', [1 1 0])
            end
            set(app.Lamp, 'Color', [0 1 0])
            app.T2 = clock;
            %if app.T2 - app.T1 > .05
                app.T_PAUSE = app.T_PAUSE + app.T2 - app.T1;
            %end
            move_motors(start(2), start(1), app.serv_up, 1, app.motor_y);
            app.T1 = clock;
            while is_load() == 0
                set(app.Lamp, 'Color', [1 1 0])
            end
            set(app.Lamp, 'Color', [1 1 0])
            app.T2 = clock;
            %if app.T2 - app.T1 > .05
                app.T_PAUSE = app.T_PAUSE + app.T2 - app.T1;
            %end
            
            % Move and Drop Piece
            if stop(2) <= 0
                move_motors(stop(2), stop(1), app.serv_up, 0, app.motor_y);
            else
                move_motors(stop(2), stop(1), app.serv_pick, 0, app.motor_y);
            end
            app.motor_y = stop(1);
            app.T1 = clock;
            while is_load() == 0
                set(app.Lamp, 'Color', [1 1 0])
            end
            set(app.Lamp, 'Color', [0 1 0])
            app.T2 = clock;
            %if app.T2 - app.T1 > .5
                app.T_PAUSE = app.T_PAUSE + app.T2 - app.T1;
            %end
            move_motors(stop(2), stop(1), app.serv_trav, 0, app.motor_y);
            app.T1 = clock;
            while is_load() == 0
                set(app.Lamp, 'Color', [1 1 0])
            end
            set(app.Lamp, 'Color', [0 1 0])
            app.T2 = clock;
            %if app.T2 - app.T1 > .5
                app.T_PAUSE = app.T_PAUSE + app.T2 - app.T1;
            %end
            
        end
        
        function updateboard(app)
            % Store instruction cell as a string
            ins = char(app.ins_list(app.ins_num));
            
            % Parse out piece type and move type
            [piece, mv_type] = det_ins(ins);
            if rem(app.ins_num,2) == 1
                piece = piece + 10;
            end
            % Store start and stop positions as numerical arrays
            start_pos = [double(ins(3))-'a'+1,double(ins(4))-'0'];
            stop_pos = [double(ins(5))-'a'+1,double(ins(6))-'0'];
            
            % Verify if a check move is a capture or not
            if mv_type == 3 
                set(app.CheckLamp, 'Color', [1 1 0]);
                if app.pieces(stop_pos(1),stop_pos(2),1) == 0
                    mv_type = 1;
                else
                    mv_type = 2;
                end
            end
            
            % Check if castling
            if rem(piece,10) == 6
                chk = is_castling(app,start_pos,stop_pos,piece);
                if chk ~= 0
                    return
                end
            end
            
            % Update pieces to reflect piece movement
            app.pieces(start_pos(1),start_pos(2),1) = 0;
            app.pieces(stop_pos(1),stop_pos(2),1) = piece;
            
            % Update Plot
            if mv_type == 1
                                
                plotSpace(app,start_pos(1),start_pos(2));
                plotPiece(app,stop_pos(1),stop_pos(2));
            elseif mv_type == 2 % kill
                plotSpace(app,start_pos(1),start_pos(2));
                plotSpace(app,stop_pos(1),stop_pos(2));                
                plotPiece(app,stop_pos(1),stop_pos(2));                
            end
        end
        
        function chk = is_castling(app,start_pos,stop_pos,piece)
            chk = 0;
            castling = [ [ 5, 1 ] ; [ 8, 1 ] ; [ 7, 1 ] ; [ 6, 1 ] ] ;
            castling(:,:,2) = [ [ 5, 1 ] ; [ 1, 1 ] ; [ 3, 1 ] ; [ 4, 1 ] ] ;
            castling(:,:,3) = [ [ 5, 8 ] ; [ 8, 8 ] ; [ 7, 8 ] ; [ 6, 8 ] ] ;
            castling(:,:,4) = [ [ 5, 8 ] ; [ 1, 8 ] ; [ 3, 8 ] ; [ 4, 8 ] ] ;
            for j = 1 : 4 
                if (start_pos(1) == castling(1,1,j)) && (start_pos(2) == castling(1,2,j)) && (stop_pos(1) == castling(2,1,j)) && (stop_pos(2) == castling(2,2,j))
                    if rem(j,2) == 0
                        p = 3;
                    else
                        p = 5;
                    end
                    app.pieces(start_pos(1),start_pos(2),1) = 0;
                    app.pieces(stop_pos(1),stop_pos(2),1) = 0;
                    app.pieces(castling(3,1,j),castling(3,2,j),1) = piece;
                    app.pieces(castling(4,1,j),castling(4,2,j),1) = (piece-6)+p;
                    plotSpace(app,castling(1,1,j),castling(1,2,j));
                    plotPiece(app,castling(3,1,j),castling(3,2,j));
                    plotSpace(app,castling(2,1,j),castling(2,2,j));
                    plotPiece(app,castling(4,1,j),castling(4,2,j));
                    chk = j;
                    return
                end
            end
                        
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.STOPPED = 1;
            app.RUN_TIME = 0;
            app.T2 = 0;
            app.T_PAUSE = 0;
            app.ins_num = 1;
            app.start_gp = 0;
            app.serv_up = 3;
            app.serv_pick = 12.6;
            app.serv_trav = 9;
            
            load_system('real_motor');

            set_param('real_motor','SimulationMode','external'); 
            
            set_param('real_motor','SimulationCommand','connect');
            
            set_param('real_motor','SimulationCommand','Start');
            
     
            app.TextArea.Value = formatTimeFcn(app,app.RUN_TIME);
            set(app.StartGameButton, 'Enable', 'off')
            set(app.MoveNumberEditField, 'Value', app.ins_num);
            app.types = zeros(4,3);
            app.pnames = [  [ 'BP' , 'BN' , 'BB' ] ;
                        [ 'BQ' , 'BR' , 'BK' ] ;
                        [ 'WP' , 'WN' , 'WB' ] ;
                        [ 'WQ' , 'WR' , 'WK' ] 
                     ];
            app.pieceimgs = [ ['BP.png', 'BN.png', 'BB.png'] ;
                              ['BQ.png', 'BR.png', 'BK.png'] ;
                              ['WP.png', 'WN.png', 'WB.png'] ;
                              ['WQ.png', 'WR.png', 'WB.png'] ];
            app.plotshape = [ 'o' ; 's' ; 'd' ] ;
            app.plotcolor = [ 'r' ; 'y' ; 'g' ; 'b' ] ;
            app.plotsize = [ 30 30 20 ] ;
            app.motor_y = 0;
            app.tg = xpc;
            plotBoard(app);
            app.cam = webcam('HP USB Webcam');
            [app.board,app.pieces,app.types,app.pnames] = imageproc(app.cam,app.types,4,3);
            plotPieces(app);


        end

        % Button pushed function: ScanQRCodeButton
        function ScanQRCodeButtonPushed(app, event)
            set(app.Lamp, 'Color', [1 1 0])
            scanQR(app);
            set(app.StartGameButton, 'Enable', 'on')
            set(app.Lamp, 'Color', [0 1 0])
        end

        % Button pushed function: StartGameButton
        function StartGameButtonPushed(app, event)
            if app.STOPPED == 1
                set(app.ScanQRCodeButton, 'Enable', 'off')
                set(app.StartGameButton, 'Text', 'Pause Game')
                app.STOPPED = 0;
                game_play(app);
            else
                set(app.ScanQRCodeButton, 'Enable', 'on')
                set(app.StartGameButton, 'Text', 'Start Game')
                app.STOPPED = 1;
            end
            
        end

        % Callback function
        function ButtonPushed(app, event)
            app.Lamp.Color = [ 0 0 1];
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.InstructionsPanel = uipanel(app.UIFigure);
            app.InstructionsPanel.Title = 'Instructions';
            app.ScanQRCodeButton = uibutton(app.InstructionsPanel, 'push');
            app.ScanQRCodeButton.ButtonPushedFcn = createCallbackFcn(app, @ScanQRCodeButtonPushed, true);
            app.ScanQRCodeButton.Text = 'Scan QR Code';
            app.MovesListBox = uilistbox(app.InstructionsPanel);
            app.MovesListBox.Items = {};
            app.MovesListBox.Value = {};
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XLim = [0.5 8.5];
            app.UIAxes.YLim = [0.5 8.5];
            app.UIAxes.ZLim = [0 1];
            app.UIAxes.CLim = [0 1];
            app.UIAxes.XTick = [1 2 3 4 5 6 7 8];
            app.UIAxes.XTickLabel = {'A'; 'B'; 'C'; 'D'; 'E'; 'F'; 'G'; 'H'};
            app.UIAxes.YTick = [1 2 3 4 5 6 7 8];
            app.UIAxes.ZTick = [0 0.5 1];
            app.TeamKlondikeTheArtofDigitalChessLabel = uilabel(app.UIFigure);
            app.TeamKlondikeTheArtofDigitalChessLabel.Text = 'Team Klondike: The Art of Digital Chess';
            app.GamePlayPanel = uipanel(app.UIFigure);
            app.GamePlayPanel.Title = 'Game Play';
            app.CurrentInstructionTextAreaLabel = uilabel(app.GamePlayPanel);
            app.CurrentInstructionTextAreaLabel.Text = 'Current Instruction';
            app.CurrentInstructionTextArea = uitextarea(app.GamePlayPanel);
            app.CurrentInstructionTextArea.Editable = 'off';
            app.TextArea = uitextarea(app.GamePlayPanel);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'00:00:00.000'};
            app.StartGameButton = uibutton(app.GamePlayPanel, 'push');
            app.StartGameButton.ButtonPushedFcn = createCallbackFcn(app, @StartGameButtonPushed, true);
            app.StartGameButton.Text = 'Start Game';
            app.LampLabel = uilabel(app.GamePlayPanel);
            app.LampLabel.Text = '';
            app.Lamp = uilamp(app.GamePlayPanel);
            app.MoveNumberEditFieldLabel = uilabel(app.GamePlayPanel);
            app.MoveNumberEditFieldLabel.Text = 'Move Number: ';
            app.MoveNumberEditField = uieditfield(app.GamePlayPanel, 'numeric');
            app.CheckLabel = uilabel(app.GamePlayPanel);
            app.CheckLabel.Text = 'Check:';
            app.CheckLamp = uilamp(app.GamePlayPanel);
        end
    end

    methods (Access = public)

        % Construct app
        function app = chess

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end